classdef BHCSEO_UI < matlab.apps.AppBase
    %BHCSEO_UI --GUI of the proposed BHCSEO Algorithm for Cryptanalysis of
    %Internal State of RC4--
    %   A GUI to show the performance of the proposed BHCSEO in terms of:
    %   Best Fitness, Worst Fitness, Average, Standard Deviation, Time, and
    %   Memory Usage with the following population sizes: 50, 60, 70, 80,
    %   90, 100.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        compare_icon           matlab.ui.control.Image
        attack_icon            matlab.ui.control.Image
        COMPAREButton          matlab.ui.control.Button
        hundred_memory         matlab.ui.control.EditField
        ninety_memory          matlab.ui.control.EditField
        eighty_memory          matlab.ui.control.EditField
        seventy_memory         matlab.ui.control.EditField
        sixty_memory           matlab.ui.control.EditField
        fifty_memory           matlab.ui.control.EditField
        ninety_time            matlab.ui.control.EditField
        eighty_time            matlab.ui.control.EditField
        hundred_time           matlab.ui.control.EditField
        sixty_time             matlab.ui.control.EditField
        seventy_time           matlab.ui.control.EditField
        fifty_time             matlab.ui.control.EditField
        ninety_sd              matlab.ui.control.EditField
        eighty_sd              matlab.ui.control.EditField
        hundred_sd             matlab.ui.control.EditField
        sixty_sd               matlab.ui.control.EditField
        seventy_sd             matlab.ui.control.EditField
        fifty_sd               matlab.ui.control.EditField
        ninety_ave             matlab.ui.control.EditField
        eighty_ave             matlab.ui.control.EditField
        hundred_ave            matlab.ui.control.EditField
        sixty_ave              matlab.ui.control.EditField
        seventy_ave            matlab.ui.control.EditField
        fifty_ave              matlab.ui.control.EditField
        ninety_worst           matlab.ui.control.EditField
        eighty_worst           matlab.ui.control.EditField
        hundred_worst          matlab.ui.control.EditField
        sixty_worst            matlab.ui.control.EditField
        seventy_worst          matlab.ui.control.EditField
        fifty_worst            matlab.ui.control.EditField
        ninety_best            matlab.ui.control.EditField
        eighty_best            matlab.ui.control.EditField
        hundred_best           matlab.ui.control.EditField
        sixty_best             matlab.ui.control.EditField
        seventy_best           matlab.ui.control.EditField
        fifty_best             matlab.ui.control.EditField
        ninety_n               matlab.ui.control.EditField
        eighty_n               matlab.ui.control.EditField
        hundred_n              matlab.ui.control.EditField
        sixty_n                matlab.ui.control.EditField
        seventy_n              matlab.ui.control.EditField
        fifty_n                matlab.ui.control.EditField
        NoofParticlesDropDown  matlab.ui.control.DropDown
        ATTACKButton           matlab.ui.control.Button
        message                matlab.ui.control.EditField
        key                    matlab.ui.control.EditField
        Background             matlab.ui.control.Image
    end

    
    properties (Access = private)
        compare % To call compare page
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ATTACKButton
        function ATTACKButtonPushed(app, event)
% %    clc;                                                 % Clear Command Window                                                    
   
   %---Confirmation Box---%                                                   
   quest = ' \fontsize{18} \fontname{Tw Cen MT} Do you want to continue?';
   opts.Interpreter = 'tex';
   opts.Default = 'Yes';
   selection = questdlg(quest,'Confirm Attack',...
                  'Yes','No',opts);
   %---Confirmation Box---%    

    switch selection    
          
        case 'Yes'
    
            %---------------- Restrictions------------------% 
            % Only 8 characters or 64 bits for the key and 16 characters or 128 bits for the message/plaintext.
            if (numel(app.key.Value) ~= 8 | numel(app.message.Value) ~= 16)
                     % Error message
                     opts = struct('WindowStyle','modal',... 
                                      'Interpreter','tex');
                     lenght_error = errordlg('\fontsize{18} \fontname{Tw Cen MT} Key or Message Length Error',...
                         '             Text Length Error', opts);                  
            end
              
            % The input key and plaintext must be from the English alphabet.
            if ((all(isletter(app.key.Value)) == false) | (all(isletter(app.message.Value)) == false))
                     % Error message
                     opts = struct('WindowStyle','modal',... 
                                      'Interpreter','tex');
                     input_error = errordlg('\fontsize{18} \fontname{Tw Cen MT} Input Error',...
                         '             Error', opts);                   
            end
            %---------------- Restrictions------------------% 

            if (numel(app.key.Value) == 8 & numel(app.message.Value) == 16)
                 if ((all(isletter(app.key.Value)) == true) & (all(isletter(app.message.Value)) == true))
                       
                        f = waitbar(0,'Please wait...');            % Loading Bar
                        pause(.5)
              
                        tic;                                        % Start timer

                        %INPUT
                        Input_key = app.key.Value;                  % Key
                        Input_plaintext = app.message.Value;        % Plaintext/Message
            
                        rc4 = RC4(Input_key,Input_plaintext);       % RC4 Function Call
                        
                        waitbar(.33,f,'Loading your data');         % Loading Bar
                        pause(1)
            
                        switch app.NoofParticlesDropDown.Value      % Determine Number of Particles/ Population
                            case '50'
                                Particles_no = 50;
                            case '60'
                                Particles_no = 60;
                            case '70'
                                Particles_no = 70;
                            case '80'
                                Particles_no = 80;
                            case '90'
                                Particles_no = 90;
                            case '100'
                                Particles_no = 100;
                        end
            
                        [Best, Worstt, Mean, Sd] = BHCSEO(rc4, Particles_no);  % BHCSEO Function Call
                        
                        waitbar(.67,f,'Processing your data');        % Loading Bar
                        pause(1)
            
                        %---Convert variables from BHCSEO function to string---%
                        timE = num2str(toc,2);                         % Elapsed Time
                        Ave = num2str(Mean,5);                         % Average Fitness
                        Std = num2str(Sd,6);                           % Standard Deviation 
                        Worst = num2str(Worstt,5);                     % Worst Fitness
                        The_Best = num2str(Best,5);                    % Best Fitness
                        Population = num2str(Particles_no);            % Number of Particles
                        %---Convert variables from BHCSEO function to string---%
                        
                        waitbar(1,f,'Finishing');                      % Loading Bar
                        pause(1)
                        
                        %--Place values to their designated field text--%
                        switch app.NoofParticlesDropDown.Value
                            case '50'
                               app.fifty_n.Value = Population;
                               app.fifty_best.Value = The_Best
                               app.fifty_worst.Value = Worst;
                               app.fifty_ave.Value = Ave;
                               app.fifty_sd.Value = Std;
                               app.fifty_time.Value = timE;
                               app.fifty_memory.Value = num2str(memory().MemUsedMATLAB/(1024^2));   
                               set(app.fifty_n, 'visible', 'on');
                               set(app.fifty_best, 'visible', 'on');
                               set(app.fifty_worst, 'visible', 'on');
                               set(app.fifty_ave, 'visible', 'on');
                               set(app.fifty_sd, 'visible', 'on');
                               set(app.fifty_time, 'visible', 'on');
                                set(app.fifty_memory, 'visible', 'on');
                            case '60'
                               app.sixty_n.Value = Population
                               app.sixty_best.Value = The_Best
                               app.sixty_worst.Value = Worst;
                               app.sixty_ave.Value = Ave;
                               app.sixty_sd.Value = Std;
                               app.sixty_time.Value = timE;
                               app.sixty_memory.Value = num2str (memory().MemUsedMATLAB/(1024^2));
                               set(app.sixty_n, 'visible', 'on');
                               set(app.sixty_best, 'visible', 'on');
                               set(app.sixty_worst, 'visible', 'on');
                               set(app.sixty_ave, 'visible', 'on');
                               set(app.sixty_sd, 'visible', 'on');
                               set(app.sixty_time, 'visible', 'on');
                               set(app.sixty_memory, 'visible', 'on');
                            case '70'
                               app.seventy_n.Value = Population
                               app.seventy_best.Value = The_Best
                               app.seventy_worst.Value = Worst;
                               app.seventy_ave.Value = Ave;
                               app.seventy_sd.Value = Std;
                               app.seventy_time.Value = timE;
                               app.seventy_memory.Value = num2str (memory().MemUsedMATLAB/(1024^2));
                               set(app.seventy_n, 'visible', 'on');
                               set(app.seventy_best, 'visible', 'on');
                               set(app.seventy_worst, 'visible', 'on');
                               set(app.seventy_ave, 'visible', 'on');
                               set(app.seventy_sd, 'visible', 'on');
                               set(app.seventy_time, 'visible', 'on');
                               set(app.seventy_memory, 'visible', 'on');
                            case '80'
                               app.eighty_n.Value = Population
                               app.eighty_best.Value = The_Best
                               app.eighty_worst.Value = Worst;
                               app.eighty_ave.Value = Ave;
                               app.eighty_sd.Value = Std;
                               app.eighty_time.Value = timE;
                               app.eighty_memory.Value = num2str (memory().MemUsedMATLAB/(1024^2));
                               set(app.eighty_n, 'visible', 'on');
                               set(app.eighty_best, 'visible', 'on');
                               set(app.eighty_worst, 'visible', 'on');
                               set(app.eighty_ave, 'visible', 'on');
                               set(app.eighty_sd, 'visible', 'on');
                               set(app.eighty_time, 'visible', 'on');
                               set(app.eighty_memory, 'visible', 'on');
                            case '90'
                               app.ninety_n.Value = Population
                               app.ninety_best.Value = The_Best
                               app.ninety_worst.Value = Worst;
                               app.ninety_ave.Value = Ave;
                               app.ninety_sd.Value = Std;
                               app.ninety_time.Value = timE;
                               app.ninety_memory.Value = num2str (memory().MemUsedMATLAB/(1024^2));
                               set(app.ninety_n, 'visible', 'on');
                               set(app.ninety_best, 'visible', 'on');
                               set(app.ninety_worst, 'visible', 'on');
                               set(app.ninety_ave, 'visible', 'on');
                               set(app.ninety_sd, 'visible', 'on');
                               set(app.ninety_time, 'visible', 'on');
                               set(app.ninety_memory, 'visible', 'on');
                            case '100'
                               app.hundred_n.Value = Population
                               app.hundred_best.Value = The_Best
                               app.hundred_worst.Value = Worst;
                               app.hundred_ave.Value = Ave;
                               app.hundred_sd.Value = Std;
                               app.hundred_time.Value = timE;
                               app.hundred_memory.Value = num2str (memory().MemUsedMATLAB/(1024^2));
                               set(app.hundred_n, 'visible', 'on');
                               set(app.hundred_best, 'visible', 'on');
                               set(app.hundred_worst, 'visible', 'on');
                               set(app.hundred_ave, 'visible', 'on');
                               set(app.hundred_sd, 'visible', 'on');
                               set(app.hundred_time, 'visible', 'on');
                               set(app.hundred_memory, 'visible', 'on');
                        end
                        %--Place the values to their designated field text--%

                        close(f)                                   % Close Loading Bar
            
                        %---Make Compare button visible---%
                        if(app.fifty_n.Value > 1)    
                            if(app.sixty_n.Value > 1)
                                if(app.seventy_n.Value > 1)
                                    if(app.eighty_n.Value > 1)
                                        if(app.ninety_n.Value > 1)
                                            if(app.hundred_n.Value > 1)
                                                set(app.COMPAREButton, 'visible', 'on');
                                                set(app.compare_icon, 'visible', 'on');
                                            end
                                        end
                                    end
                                end
                            end
                        end
                      %---Make Compare button visible---%

                 end    
            end
             
                case 'No'
                       return
                end
        end

        % Button pushed function: COMPAREButton
        function COMPAREButtonPushed(app, event)
        %--Transfer the field text values to specific variables--%
        f_Population = app.fifty_n.Value;
        f_The_Best = app.fifty_best.Value; 
        f_Worst = app.fifty_worst.Value;
        f_Ave = app.fifty_ave.Value;
        f_Std = app.fifty_sd.Value ;
        f_timE =  app.fifty_time.Value;
            
        s_Population = app.sixty_n.Value;
        s_The_Best = app.sixty_best.Value; 
        s_Worst = app.sixty_worst.Value;
        s_Ave = app.sixty_ave.Value;
        s_Std = app.sixty_sd.Value ;
        s_timE =  app.sixty_time.Value;

        se_Population = app.seventy_n.Value;
        se_The_Best = app.seventy_best.Value; 
        se_Worst = app.seventy_worst.Value;
        se_Ave = app.seventy_ave.Value;
        se_Std = app.seventy_sd.Value ;
        se_timE =  app.seventy_time.Value;

        e_Population = app.eighty_n.Value;
        e_The_Best = app.eighty_best.Value; 
        e_Worst = app.eighty_worst.Value;
        e_Ave = app.eighty_ave.Value;
        e_Std = app.eighty_sd.Value ;
        e_timE =  app.eighty_time.Value;

        n_Population = app.ninety_n.Value;
        n_The_Best = app.ninety_best.Value; 
        n_Worst = app.ninety_worst.Value;
        n_Ave = app.ninety_ave.Value;
        n_Std = app.ninety_sd.Value ;
        n_timE =  app.ninety_time.Value;

        h_Population = app.hundred_n.Value;
        h_The_Best = app.hundred_best.Value; 
        h_Worst = app.hundred_worst.Value;
        h_Ave = app.hundred_ave.Value;
        h_Std = app.hundred_sd.Value ;
        h_timE =  app.hundred_time.Value;
        %--Transfer the field text values to specific variables--%

        Population = {f_Population s_Population se_Population e_Population n_Population h_Population};
        Best       = {f_The_Best s_The_Best se_The_Best e_The_Best n_The_Best h_The_Best};
        Worst      = {f_Worst, s_Worst, se_Worst, e_Worst, n_Worst, h_Worst};
        Average    = {f_Ave s_Ave se_Ave e_Ave n_Ave h_Ave};
        StD        = {f_Std s_Std se_Std e_Std n_Std h_Std};
        Time       = {f_timE s_timE se_timE e_timE n_timE h_timE}; 
        app.compare = Compare_PSOEO(app, Population, Best, Worst, Average, StD, Time);    % Call and Pass values to Compare_PSOEO page
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'BHCSEO UI.png');
            app.key = uieditfield(app.UIFigure, 'text');
            app.key.CharacterLimits = [0 8];
            app.key.Placeholder = 'Enter 8 character English alphabet';
            app.message = uieditfield(app.UIFigure, 'text');
            app.message.CharacterLimits = [0 16];
            app.message.Placeholder = 'Enter 16 character English alphabet';
            app.ATTACKButton = uibutton(app.UIFigure, 'push');
            app.ATTACKButton.ButtonPushedFcn = createCallbackFcn(app, @ATTACKButtonPushed, true);
            app.ATTACKButton.Text = '   ATTACK';
            app.NoofParticlesDropDown = uidropdown(app.UIFigure);
            app.NoofParticlesDropDown.Items = {'50', '60', '70', '80', '90', '100'};
            app.NoofParticlesDropDown.Value = '50';
            app.fifty_n = uieditfield(app.UIFigure, 'text');
            app.fifty_n.Editable = 'off';
            app.fifty_n.Visible = 'off';
            app.seventy_n = uieditfield(app.UIFigure, 'text');
            app.seventy_n.Editable = 'off';
            app.seventy_n.Visible = 'off';
            app.sixty_n = uieditfield(app.UIFigure, 'text');
            app.sixty_n.Editable = 'off';
            app.sixty_n.Visible = 'off';
            app.hundred_n = uieditfield(app.UIFigure, 'text');
            app.hundred_n.Editable = 'off';
            app.hundred_n.Visible = 'off';
            app.eighty_n = uieditfield(app.UIFigure, 'text');
            app.eighty_n.Editable = 'off';
            app.eighty_n.Visible = 'off';
            app.ninety_n = uieditfield(app.UIFigure, 'text');
            app.ninety_n.Editable = 'off';
            app.ninety_n.Visible = 'off';
            app.fifty_best = uieditfield(app.UIFigure, 'text');
            app.fifty_best.Editable = 'off';
            app.fifty_best.Visible = 'off';
            app.seventy_best = uieditfield(app.UIFigure, 'text');
            app.seventy_best.Editable = 'off';
            app.seventy_best.Visible = 'off';
            app.sixty_best = uieditfield(app.UIFigure, 'text');
            app.sixty_best.Editable = 'off';
            app.sixty_best.Visible = 'off';
            app.hundred_best = uieditfield(app.UIFigure, 'text');
            app.hundred_best.Editable = 'off';
            app.hundred_best.Visible = 'off';
            app.eighty_best = uieditfield(app.UIFigure, 'text');
            app.eighty_best.Editable = 'off';
            app.eighty_best.Visible = 'off';
            app.ninety_best = uieditfield(app.UIFigure, 'text');
            app.ninety_best.Editable = 'off';
            app.ninety_best.Visible = 'off';
            app.fifty_worst = uieditfield(app.UIFigure, 'text');
            app.fifty_worst.Editable = 'off';
            app.fifty_worst.Visible = 'off';
            app.seventy_worst = uieditfield(app.UIFigure, 'text');
            app.seventy_worst.Editable = 'off';
            app.seventy_worst.Visible = 'off';
            app.sixty_worst = uieditfield(app.UIFigure, 'text');
            app.sixty_worst.Editable = 'off';
            app.sixty_worst.Visible = 'off';
            app.hundred_worst = uieditfield(app.UIFigure, 'text');
            app.hundred_worst.Editable = 'off';
            app.hundred_worst.Visible = 'off';
            app.eighty_worst = uieditfield(app.UIFigure, 'text');
            app.eighty_worst.Editable = 'off';
            app.eighty_worst.Visible = 'off';
            app.ninety_worst = uieditfield(app.UIFigure, 'text');
            app.ninety_worst.Editable = 'off';
            app.ninety_worst.Visible = 'off';
            app.fifty_ave = uieditfield(app.UIFigure, 'text');
            app.fifty_ave.Editable = 'off';
            app.fifty_ave.Visible = 'off';
            app.seventy_ave = uieditfield(app.UIFigure, 'text');
            app.seventy_ave.Editable = 'off';
            app.seventy_ave.Visible = 'off';
            app.sixty_ave = uieditfield(app.UIFigure, 'text');
            app.sixty_ave.Editable = 'off';
            app.sixty_ave.Visible = 'off';
            app.hundred_ave = uieditfield(app.UIFigure, 'text');
            app.hundred_ave.Editable = 'off';
            app.hundred_ave.Visible = 'off';
            app.eighty_ave = uieditfield(app.UIFigure, 'text');
            app.eighty_ave.Editable = 'off';
            app.eighty_ave.Visible = 'off';
            app.ninety_ave = uieditfield(app.UIFigure, 'text');
            app.ninety_ave.Editable = 'off';
            app.ninety_ave.Visible = 'off';
            app.fifty_sd = uieditfield(app.UIFigure, 'text');
            app.fifty_sd.Editable = 'off';
            app.fifty_sd.Visible = 'off';
            app.seventy_sd = uieditfield(app.UIFigure, 'text');
            app.seventy_sd.Editable = 'off';
            app.seventy_sd.Visible = 'off';
            app.sixty_sd = uieditfield(app.UIFigure, 'text');
            app.sixty_sd.Editable = 'off';
            app.sixty_sd.Visible = 'off';
            app.hundred_sd = uieditfield(app.UIFigure, 'text');
            app.hundred_sd.Editable = 'off';
            app.hundred_sd.Visible = 'off';
            app.eighty_sd = uieditfield(app.UIFigure, 'text');
            app.eighty_sd.Editable = 'off';
            app.eighty_sd.Visible = 'off';
            app.ninety_sd = uieditfield(app.UIFigure, 'text');
            app.ninety_sd.Editable = 'off';
            app.ninety_sd.Visible = 'off';
            app.fifty_time = uieditfield(app.UIFigure, 'text');
            app.fifty_time.Editable = 'off';
            app.fifty_time.Visible = 'off';
            app.seventy_time = uieditfield(app.UIFigure, 'text');
            app.seventy_time.Editable = 'off';
            app.seventy_time.Visible = 'off';
            app.sixty_time = uieditfield(app.UIFigure, 'text');
            app.sixty_time.Editable = 'off';
            app.sixty_time.Visible = 'off';
            app.hundred_time = uieditfield(app.UIFigure, 'text');
            app.hundred_time.Editable = 'off';
            app.hundred_time.Visible = 'off';
            app.eighty_time = uieditfield(app.UIFigure, 'text');
            app.eighty_time.Editable = 'off';
            app.eighty_time.Visible = 'off';
            app.ninety_time = uieditfield(app.UIFigure, 'text');
            app.ninety_time.Editable = 'off';
            app.ninety_time.Visible = 'off';
            app.fifty_memory = uieditfield(app.UIFigure, 'text');
            app.fifty_memory.Editable = 'off';
            app.fifty_memory.Visible = 'off';
            app.sixty_memory = uieditfield(app.UIFigure, 'text');
            app.sixty_memory.Editable = 'off';
            app.sixty_memory.Visible = 'off';
            app.seventy_memory = uieditfield(app.UIFigure, 'text');
            app.seventy_memory.Editable = 'off';
            app.seventy_memory.Visible = 'off';
            app.eighty_memory = uieditfield(app.UIFigure, 'text');
            app.eighty_memory.Editable = 'off';
            app.eighty_memory.Visible = 'off';
            app.ninety_memory = uieditfield(app.UIFigure, 'text');
            app.ninety_memory.Editable = 'off';
            app.ninety_memory.Visible = 'off';
            app.hundred_memory = uieditfield(app.UIFigure, 'text');
            app.hundred_memory.Editable = 'off';
            app.hundred_memory.Visible = 'off';
            app.COMPAREButton = uibutton(app.UIFigure, 'push');
            app.COMPAREButton.ButtonPushedFcn = createCallbackFcn(app, @COMPAREButtonPushed, true);
            app.COMPAREButton.Visible = 'off';
            app.COMPAREButton.Text = '    COMPARE';
            app.attack_icon = uiimage(app.UIFigure);
            app.attack_icon.ImageSource = fullfile(pathToMLAPP, 'attack Icon.png');
            app.compare_icon = uiimage(app.UIFigure);
            app.compare_icon.Visible = 'off';
            app.compare_icon.ImageSource = fullfile(pathToMLAPP, 'compare Icon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BHCSEO_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end