classdef ConvergenceCurve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        DropDown    matlab.ui.control.DropDown
        PLOTButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp
        Con_curve
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BHCSEO_UI, Ccurve)
            app.callingapp = BHCSEO_UI;
            app.Con_curve = Ccurve;
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            switch app.DropDown.Value
                case '50'
                    plot(app.UIAxes,app.Con_curve{1},'-*m');
                case '60'
                    plot(app.UIAxes,app.Con_curve{2},'-*m');
                case '70'
                    plot(app.UIAxes,app.Con_curve{3},'-*m');
                case '80'
                    plot(app.UIAxes,app.Con_curve{4},'-*m');
                case '90'
                    plot(app.UIAxes,app.Con_curve{5},'-*m');
                case '100'
                    plot(app.UIAxes,app.Con_curve{6},'-*m');

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Convergence curve '; 'Particles number = '})
            xlabel(app.UIAxes, 'Iteration')
            ylabel(app.UIAxes, 'Best score obtained so far')
            zlabel(app.UIAxes, 'Z')
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Icon = fullfile(pathToMLAPP, 'plot.png');
            app.PLOTButton.Text = 'PLOT';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'50', '60', '70', '80', '90', '100'};
            app.DropDown.Value = '50';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConvergenceCurve(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end