classdef compare_psoeo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Hyperlink              matlab.ui.control.Hyperlink
        NoofParticlesDropDown  matlab.ui.control.DropDown
        VIEWButton             matlab.ui.control.Button
        pseo_ave               matlab.ui.control.EditField
        pseo_best              matlab.ui.control.EditField
        pseo_time              matlab.ui.control.EditField
        pseo_sd                matlab.ui.control.EditField
        ceso_time              matlab.ui.control.EditField
        cseo_sd                matlab.ui.control.EditField
        cseo_ave               matlab.ui.control.EditField
        pseo_worst             matlab.ui.control.EditField
        cseo_worst             matlab.ui.control.EditField
        cseo_best              matlab.ui.control.EditField
        Image                  matlab.ui.control.Image
    end

    
    properties (Access = private)
        callingapp % Call the main UI
        % Make it a property to be accessible to other functions
        best  
        worst
        average
        std
        time
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BHCSEO_UI, Population, Best, Worst, Average, StD, Time)
            app.callingapp = BHCSEO_UI;
            app.best = Best;
            app.worst = Worst;
            app.average =Average;
            app.std = StD;
            app.time = Time;
        end

        % Button pushed function: VIEWButton
        function VIEWButtonPushed(app, event)
             switch app.NoofParticlesDropDown.Value
                case '50'
                    app.cseo_best.Value = app.best{1};    app.pseo_best.Value = '1';
                    app.cseo_worst.Value = app.worst{1};  app.pseo_worst.Value = '0.88281';
                    app.cseo_ave.Value = app.average{1};  app.pseo_ave.Value = '0.95281';
                    app.cseo_sd.Value = app.std{1};       app.pseo_sd.Value = '0.031645';
                    app.ceso_time.Value = app.time{1};    app.pseo_time.Value = '127.03';
                case '60'
                    app.cseo_best.Value = app.best{2};    app.pseo_best.Value = '1';
                    app.cseo_worst.Value = app.worst{2};  app.pseo_worst.Value = '0.85156';
                    app.cseo_ave.Value = app.average{2};  app.pseo_ave.Value = '0.96344';
                    app.cseo_sd.Value = app.std{2};       app.pseo_sd.Value = '0.031585';
                    app.ceso_time.Value = app.time{2};    app.pseo_time.Value = '105.28';
                case '70'
                    app.cseo_best.Value = app.best{3};    app.pseo_best.Value = '1';
                    app.cseo_worst.Value = app.worst{3};  app.pseo_worst.Value = '0.89844';
                    app.cseo_ave.Value = app.average{3};  app.pseo_ave.Value = '0.96703';
                    app.cseo_sd.Value = app.std{3};       app.pseo_sd.Value = '0.030658';
                    app.ceso_time.Value = app.time{3};    app.pseo_time.Value = '176.86';
                case '80' 
                    app.cseo_best.Value = app.best{4};    app.pseo_best.Value = '1';
                    app.cseo_worst.Value = app.worst{4};  app.pseo_worst.Value = '0.90625';
                    app.cseo_ave.Value = app.average{4};  app.pseo_ave.Value = '0.97188';
                    app.cseo_sd.Value = app.std{4};       app.pseo_sd.Value = '0.026222';
                    app.ceso_time.Value = app.time{4};    app.pseo_time.Value = '201.98';
                case '90'
                    app.cseo_best.Value = app.best{5};    app.pseo_best.Value = '1';
                    app.cseo_worst.Value = app.worst{5};  app.pseo_worst.Value = '0.90625';
                    app.cseo_ave.Value = app.average{5};  app.pseo_ave.Value = '0.97453';
                    app.cseo_sd.Value = app.std{5};       app.pseo_sd.Value = '0.022422';
                    app.ceso_time.Value = app.time{5};    app.pseo_time.Value = '225.69';
                case '100'
                    app.cseo_best.Value = app.best{6};    app.pseo_best.Value = '1';
                    app.cseo_worst.Value = app.worst{6};  app.pseo_worst.Value = '0.91406';
                    app.cseo_ave.Value = app.average{6};  app.pseo_ave.Value = '0.97188';
                    app.cseo_sd.Value = app.std{6};       app.pseo_sd.Value = '0.025204';
                    app.ceso_time.Value = app.time{6};    app.pseo_time.Value = '246.83';
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '2(2).png');
            app.cseo_best = uieditfield(app.UIFigure, 'text');
            app.cseo_best.Editable = 'off';
            app.cseo_worst = uieditfield(app.UIFigure, 'text');
            app.cseo_worst.Editable = 'off';
            app.pseo_worst = uieditfield(app.UIFigure, 'text');
            app.pseo_worst.Editable = 'off';
            app.cseo_ave = uieditfield(app.UIFigure, 'text');
            app.cseo_ave.Editable = 'off';
            app.cseo_sd = uieditfield(app.UIFigure, 'text');
            app.cseo_sd.Editable = 'off';
            app.ceso_time = uieditfield(app.UIFigure, 'text');
            app.ceso_time.Editable = 'off';
            app.pseo_sd = uieditfield(app.UIFigure, 'text');
            app.pseo_sd.Editable = 'off';
            app.pseo_time = uieditfield(app.UIFigure, 'text');
            app.pseo_time.Editable = 'off';
            app.pseo_best = uieditfield(app.UIFigure, 'text');
            app.pseo_best.Editable = 'off';
            app.pseo_ave = uieditfield(app.UIFigure, 'text');
            app.pseo_ave.Editable = 'off';
            app.VIEWButton = uibutton(app.UIFigure, 'push');
            app.VIEWButton.ButtonPushedFcn = createCallbackFcn(app, @VIEWButtonPushed, true);
            app.VIEWButton.Icon = fullfile(pathToMLAPP, 'report.png');
            app.VIEWButton.Text = 'VIEW';
            app.NoofParticlesDropDown = uidropdown(app.UIFigure);
            app.NoofParticlesDropDown.Items = {'50', '60', '70', '80', '90', '100'};
            app.NoofParticlesDropDown.Value = '50';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.Tooltip = {''};
            app.Hyperlink.URL = 'https://www.researchgate.net/figure/BHPSEO-BEO-and-BPSO-numerical-results-for-cryptanalysis-of-the-RC4-cipher-of-a-128-bit_tbl3_360729280';
            app.Hyperlink.Text = 'Source';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = compare_psoeo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end